classdef AddEdge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ADDEDGELabel            matlab.ui.control.Label
        AcceptButton            matlab.ui.control.Button
        EdgefromEditFieldLabel  matlab.ui.control.Label
        EdgefromEditField       matlab.ui.control.EditField
        EdgetoEditFieldLabel    matlab.ui.control.Label
        EdgetoEditField         matlab.ui.control.EditField
        EdgenameEditFieldLabel  matlab.ui.control.Label
        EdgenameEditField       matlab.ui.control.EditField
    end

    % OKNO DODAWANIA KRAWĘDZI
    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, G)
            
        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            global G;
            x=app.EdgefromEditField.Value;
            y=app.EdgetoEditField.Value;
            z=app.EdgenameEditField.Value;
            [G,e]=addingEdge(G,x,y,z);
            if e==1
                uialert(app.UIFigure,'Can not add edge because it already exists!','Addition error');
            else
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ADDEDGELabel = uilabel(app.UIFigure);
            app.ADDEDGELabel.Text = 'ADD EDGE';
            app.AcceptButton = uibutton(app.UIFigure, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.EdgefromEditFieldLabel = uilabel(app.UIFigure);
            app.EdgefromEditFieldLabel.Text = 'Edge from:';
            app.EdgefromEditField = uieditfield(app.UIFigure, 'text');
            app.EdgetoEditFieldLabel = uilabel(app.UIFigure);
            app.EdgetoEditFieldLabel.Text = 'Edge to:';
            app.EdgetoEditField = uieditfield(app.UIFigure, 'text');
            app.EdgenameEditFieldLabel = uilabel(app.UIFigure);
            app.EdgenameEditFieldLabel.Text = 'Edge name:';
            app.EdgenameEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddEdge(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end