classdef NRKB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image                         matlab.ui.control.Image
        NATORIFLESKNOWLEDGEBASELabel  matlab.ui.control.Label
        USEREditFieldLabel            matlab.ui.control.Label
        USEREditField                 matlab.ui.control.EditField
        COMPUTEREditFieldLabel        matlab.ui.control.Label
        COMPUTEREditField             matlab.ui.control.EditField
        ViewnetworkButton             matlab.ui.control.Button
        AddedgeButton                 matlab.ui.control.Button
        DeleteedgeButton              matlab.ui.control.Button
        AddnodeButton                 matlab.ui.control.Button
        DeletenodeButton              matlab.ui.control.Button
        SavechangesButton             matlab.ui.control.Button
        AskquestionButton             matlab.ui.control.Button
        USERMANUALREADBEFOREUSINGButton  matlab.ui.control.Button
    end

    %WYWOŁANIE DODATKOWYCH OKIEN
    
    properties (Access = private)
        Addedge % Description
        Deleteedge
        Addnode
        Deletenode
        Usermanual
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % ZAŁADOWANIE SIECI SEMANTYCZNEJ
            global G;
            load('SemWebNatoRifle.mat',"G");
            x=("Welcome in NATO rifle knowledge base! Semantic Network has been loaded.");
            app.COMPUTEREditField.Value=x;
        end

        % Button pushed function: ViewnetworkButton
        function ViewnetworkButtonPushed(app, event)
            % WYŚWIETLENIE SIECI SEMANTYCZNEJ
            global G;
            plot(G,'EdgeLabel',G.Edges.EdgeName,'Layout','auto');
        end

        % Button pushed function: AddedgeButton
        function AddedgeButtonPushed(app, event)
            % GUZIK DODANIA KRAWĘDZI, WYWOŁUJE NASZE DODATKOWE OKNO, W
            % KTÓRYM JEST WYWOŁYWANA NASZA FUNKCJA DODAJĄCA KRAWĘDŹ
            
            global G;
            % Open the options dialog and pass inputs
            app.Addedge = AddEdge(app,G);
        end

        % Button pushed function: DeleteedgeButton
        function DeleteedgeButtonPushed(app, event)
            % GUZIK USUNIĘCIA KRAWĘDZI, WYWOŁUJE NASZE DODATKOWE OKNO, W
            % KTÓRYM JEST WYWOŁYWANA NASZA FUNKCJA USUWAJĄCA KRAWĘDŹ
            
            global G;
            % Open the options dialog and pass inputs
            app.Deleteedge = DeleteEdge(app,G);
        end

        % Button pushed function: AddnodeButton
        function AddnodeButtonPushed(app, event)
            % GUZIK DODANIA WĘZŁA, WYWOŁUJE NASZE DODATKOWE OKNO, W
            % KTÓRYM JEST WYWOŁYWANA NASZA FUNKCJA DODAJĄCA WĘZEŁ
            
            global G;
            app.Addnode = AddNode(app,G);
        end

        % Button pushed function: DeletenodeButton
        function DeletenodeButtonPushed(app, event)
            % GUZIK USUNIĘCIA WĘZŁA, WYWOŁUJE NASZE DODATKOWE OKNO, W
            % KTÓRYM JEST WYWOŁYWANA NASZA FUNKCJA USUWAJĄCA WĘZEŁ
            
            global G;
            app.Deletenode = DeleteNode(app,G);
        end

        % Button pushed function: SavechangesButton
        function SavechangesButtonPushed(app, event)
            % GUZIK ZAPISUJĄCY ZMIANY WPROWADZANE NA SIECI SEMANTYCZNEJ
            
            global G;
            uisave({'G'},'SemWebNatoRifle');
        end

        % Button pushed function: AskquestionButton
        function AskquestionButtonPushed(app, event)
            % GUZIK GŁÓWNY. WYWOŁUJE FUNKCJE SPRAWDZAJĄCE NASZE PYTANIE
            
            global G;

            
            matchStr=regexread(G,app.USEREditField.Value); % WYWOŁANIE WYRAŻENIA REGULARNEGO WYNIK: SŁOWA W PYTANIU KTÓRE SĄ TEŻ W TABELI
            if isempty(matchStr) || length(matchStr)==1
                app.COMPUTEREditField.Value="Answer not found! Ask a different question or check the spelling"; % JEŚLI NIE ZADANO PYTANIA I WCIŚNIETO GUZIK, ALBO ZADANO TAK GŁUPIE PYTANIE ŻE WYRAŻENIE REGULARNE NICZEGO NIE WYKRYŁO
            else
                if length(matchStr)>3
                    output=longpath(G,matchStr);
                    app.COMPUTEREditField.Value=string(output); % JEŚLI W PYTANIU ZOSTAŁY PODANE 3 INFORMACJE BĘDĄCE W SIECI SEM. TZN. ŻE SCIEŻKA WYSZUKIWANIA BĘDZIE DŁUŻSZA WIĘC TE 0, 1.
                else
                    output=regexpcheck(G,matchStr); % JEŚLI WYKRYTO MNIEJ NIŻ 3 INFO W PYTANIU, ZNACZY ŻE ODPOWIEDŹ BĘDZIE BLISKO
                    if length(output)==1
                        if iscell(output)==0 && output==1
                            app.COMPUTEREditField.Value="Question not allowed!";
                        else
                            app.COMPUTEREditField.Value=string(output); % jEŚLI ODPOWIEDZIĄ JEST JEDNA INFORMACJA
                        end
                    else
                        app.COMPUTEREditField.Value=string(join(output,", ")); % jEŚLI ODPOWIEDZIĄ JEST KILKA INFORMACJI
                    end
                end
            end
        end

        % Button pushed function: USERMANUALREADBEFOREUSINGButton
        function USERMANUALREADBEFOREUSINGButtonPushed(app, event)
            app.Usermanual=Usermanual(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Woodland-Camo.jpg';
            app.NATORIFLESKNOWLEDGEBASELabel = uilabel(app.UIFigure);
            app.NATORIFLESKNOWLEDGEBASELabel.Text = 'NATO RIFLES KNOWLEDGE BASE';
            app.USEREditFieldLabel = uilabel(app.UIFigure);
            app.USEREditFieldLabel.Text = 'USER:';
            app.USEREditField = uieditfield(app.UIFigure, 'text');
            app.COMPUTEREditFieldLabel = uilabel(app.UIFigure);
            app.COMPUTEREditFieldLabel.Text = 'COMPUTER:';
            app.COMPUTEREditField = uieditfield(app.UIFigure, 'text');
            app.ViewnetworkButton = uibutton(app.UIFigure, 'push');
            app.ViewnetworkButton.ButtonPushedFcn = createCallbackFcn(app, @ViewnetworkButtonPushed, true);
            app.ViewnetworkButton.Text = 'View network';
            app.AddedgeButton = uibutton(app.UIFigure, 'push');
            app.AddedgeButton.ButtonPushedFcn = createCallbackFcn(app, @AddedgeButtonPushed, true);
            app.AddedgeButton.Text = 'Add edge';
            app.DeleteedgeButton = uibutton(app.UIFigure, 'push');
            app.DeleteedgeButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteedgeButtonPushed, true);
            app.DeleteedgeButton.Text = 'Delete edge';
            app.AddnodeButton = uibutton(app.UIFigure, 'push');
            app.AddnodeButton.ButtonPushedFcn = createCallbackFcn(app, @AddnodeButtonPushed, true);
            app.AddnodeButton.Text = 'Add node';
            app.DeletenodeButton = uibutton(app.UIFigure, 'push');
            app.DeletenodeButton.ButtonPushedFcn = createCallbackFcn(app, @DeletenodeButtonPushed, true);
            app.DeletenodeButton.Text = 'Delete node';
            app.SavechangesButton = uibutton(app.UIFigure, 'push');
            app.SavechangesButton.ButtonPushedFcn = createCallbackFcn(app, @SavechangesButtonPushed, true);
            app.SavechangesButton.Text = 'Save changes';
            app.AskquestionButton = uibutton(app.UIFigure, 'push');
            app.AskquestionButton.ButtonPushedFcn = createCallbackFcn(app, @AskquestionButtonPushed, true);
            app.AskquestionButton.Text = 'Ask question';
            app.USERMANUALREADBEFOREUSINGButton = uibutton(app.UIFigure, 'push');
            app.USERMANUALREADBEFOREUSINGButton.ButtonPushedFcn = createCallbackFcn(app, @USERMANUALREADBEFOREUSINGButtonPushed, true);
            app.USERMANUALREADBEFOREUSINGButton.Text = {'USER MANUAL!'; ''; 'READ BEFORE USING!!'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NRKB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end